classdef SimPolManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RMIDataPanel                   matlab.ui.container.Panel
        UpdateTargets                  matlab.ui.control.Button
        ItemFilterDropDownLabel        matlab.ui.control.Label
        ItemFilterDropDown             matlab.ui.control.DropDown
        LinkFilterLabel_2              matlab.ui.control.Label
        rmiFilterUnresolvableCheckBox  matlab.ui.control.CheckBox
        rmiFilterSuspectedCheckBox     matlab.ui.control.CheckBox
        rmiFilterUniDirectionalCheckBox  matlab.ui.control.CheckBox
        rmiFilterItemLinkStatusDropDown  matlab.ui.control.DropDown
        SelectedRMIItemLabel           matlab.ui.control.Label
        MatlabItemTree                 matlab.ui.container.Tree
        SynchronizeselectionwithsourcetoolSwitchLabel  matlab.ui.control.Label
        MatlabSyncSelectionSwitch      matlab.ui.control.Switch
        matlabItemSearchTxtField       matlab.ui.control.EditField
        PolarionDataPanel              matlab.ui.container.Panel
        ItemFilterLabel                matlab.ui.control.Label
        polarionItemSearchTxtField     matlab.ui.control.EditField
        UpdatePolarion                 matlab.ui.control.Button
        polarionFilterUnresolvableCheckBox  matlab.ui.control.CheckBox
        polarionFilterUniDirectionalCheckBox  matlab.ui.control.CheckBox
        LinkFilterLabel                matlab.ui.control.Label
        polarionItemFilterDropDown     matlab.ui.control.DropDown
        PolarionActionLabel            matlab.ui.control.Label
        SelectedPolarionItemLabel      matlab.ui.control.Label
        PolarionItemTree               matlab.ui.container.Tree
        AutoViewUpdateSwitchLabel      matlab.ui.control.Label
        AutoViewUpdateSwitch           matlab.ui.control.Switch
        RefreshmanuallytoseechangesLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        CloseButton                    matlab.ui.control.Button
        StatusLabel                    matlab.ui.control.Label
        CopyrightLabel                 matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
        BuildLabel                     matlab.ui.control.Label
        SettingsButton                 matlab.ui.control.Button
        CreateLinkbutton               matlab.ui.control.Button
        BothSideViewButton             matlab.ui.control.StateButton
        NewAllocationFileButton        matlab.ui.control.Button
        OpenAllocationFileButton       matlab.ui.control.Button
        AllocationFileLabel            matlab.ui.control.Label
        AllocationFileValue            matlab.ui.control.Label
        PolarionBaselineLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        FSD_Logo                       matlab.ui.control.Image
        PatchButton                    matlab.ui.control.Button
        CreatelinkLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        mgrNotifyListener % RAII listener for manager notification events
        
        bSingleSideView % True if only the polarion side shall be displayed.
        
        settingsDialog % Handle for settings dialog
        patchDialog; % Handle for patch dialog
        
        matlabItemViewModel % View model for MATLAB item tree
        polarionItemViewModel % View model for Polarion item tree
        
        % Filter Management
        polarionViewFilterChanged = true; % True if Polartion filter has changed
        matlabViewFilterChanged = true; % True if MATLAB filter has changed
        defaultSearchText = "Search..."; % Text written to search field by default
        UpdateItemTree = true; % True to update the item view model automatically with every change
        
        externalSelectedMatlabIds = [] % Item IDs selected in "Sync with Source Tool" mode
        modalLock simpol.gui.ModalLock; % Helper to fake modal dialog behavior
    end
    
    properties (Access = public)
         mgr % SimPol Manager instance       
    end
    
    methods (Access = private)
        
        function notifyUser(app, eventData)
            % notifyUser Handles events of the manager backend and forwards
            % them to the user. STATUS events are displayed in the bottom
            % starus bar. All other events open a modal dialog.
            
            import simpol.utils.*;
            
            switch eventData.type
                case UserNotificationType.STATUS
                    app.StatusLabel.Text = eventData.message;
                    
                case UserNotificationType.INFO
                    uialert(app.UIFigure,...
                        eventData.message, eventData.title, ...
                        'Icon', 'info');
                    
                case UserNotificationType.WARN
                    uialert(app.UIFigure,...
                        eventData.message, eventData.title, ...
                        'Icon', 'warning');
                    
                case UserNotificationType.ERROR
                    uialert(app.UIFigure,...
                        eventData.message, eventData.title, ...
                        'Icon', 'error');                    
                otherwise
                    % Nothing
            end              
        end       
        
        function refreshGuiCreateLinkButton(app)
            try
                if isempty(app.polarionItemViewModel.getSelectedItemId()) ||...
                    isempty(app.matlabItemViewModel.getSelectedItemId()) || app.mgr.isReadOnly()
                    app.CreateLinkbutton.Enable = 'off';
                else
                    app.CreateLinkbutton.Enable = 'on';
                end
            catch ME
                app.mgr.notifyError(ME.message);
            end                
        end
        
        
        function refreshGuiControlbar(app)
            
            try                
            
                % Display shortened allocation file path
                if ~isempty(app.mgr.allocationFilePath)
                    [~, fname] = fileparts(app.mgr.allocationFilePath);
                    app.AllocationFileValue.Text = fname;
                else
                    app.AllocationFileValue.Text  = "<no allocation file>";
                end        
                
                % Buttons activation            
                if isempty(app.mgr.allocationFilePath)
                    app.SettingsButton.Enable = 'off';
                    app.PatchButton.Enable = 'off';
                elseif isempty(app.mgr.allocationFilePath)
                    app.SettingsButton.Enable = 'off';
                    app.PatchButton.Enable = 'off';              
                else
                   app.SettingsButton.Enable = 'on';
                   app.PatchButton.Enable = 'on';
                end
                
                % Set baseline
                if ~isempty(app.mgr.polarionAdapter) &&...
                        ~isempty(app.mgr.settings.BaselineRevision)
                    app.PolarionBaselineLabel.Text = "READ ONLY " + app.mgr.settings.BaselineRevision;
                else
                    app.PolarionBaselineLabel.Text = '';
                end                
            
            catch ME
                app.mgr.notifyError(ME.message);
            end            

        end


        function refreshGuiPolarionSelectionLabels(app)
            try
                itemId = app.polarionItemViewModel.getSelectedItemId();
                linkId = app.polarionItemViewModel.getSelectedLinkId();
                
                if ~isempty(app.mgr.polarionAdapter) && ~isempty(itemId)
                    
                    
                    % Update item label
                    app.SelectedPolarionItemLabel.Text = itemId;

                    if ~isempty(linkId)
                
                       [~,~,link] = app.mgr.polarionAdapter.getLinkTable().getLink(itemId, linkId);
                       if ~isempty(link)
                           s = ['Actions for link "' link.Name '" of "' link.From '"']; 
                       else
                           s = '';
                       end
                    else
                        s = ['Actions for item "' itemId '"']; 
                    end
                    app.PolarionActionLabel.Text = s;               
                    
                else
                    app.PolarionActionLabel.Text = '';
                end
                              
                
            catch ME
                app.mgr.notifyError(ME.message);
            end            
        end
                
         
        function refresh_gui_filter(app)
            % Refresh function can be called multiple time during execution

            % Refresh selection filter
            if ~isempty(app.mgr.matlabAdapter)                
                if app.MatlabSyncSelectionSwitch.Value == "On"
                    app.ItemFilterDropDown.Enable = 'off';
                    app.rmiFilterItemLinkStatusDropDown.Enable = 'off';
                    app.matlabItemSearchTxtField.Enable = 'off';
                else
                    app.ItemFilterDropDown.Enable = 'on';
                    app.rmiFilterItemLinkStatusDropDown.Enable = 'on';
                    app.matlabItemSearchTxtField.Enable = 'on';
                end
            end
        end       
        
        function load_gui_filter(app)
            % Load function is just called once when a new config file is loaded
            
            % Revert text search
            app.polarionItemSearchTxtField.Value = app.defaultSearchText;
            app.matlabItemSearchTxtField.Value = app.defaultSearchText;

            % Refresh target filter
            if ~isempty(app.mgr.settings)
                if isempty(app.mgr.settings.Targets)
                    app.ItemFilterDropDown.Items = {''};
                else
                    app.ItemFilterDropDown.Items = app.mgr.settings.Targets;
                end
            end
            
            % Update show selection
            if ~isempty(app.mgr.matlabAdapter)
                if app.mgr.matlabAdapter.providesSelection()
                    app.MatlabSyncSelectionSwitch.Enable = 'on';
                else
                    app.MatlabSyncSelectionSwitch.Enable = 'off';
                    app.MatlabSyncSelectionSwitch.Value = 'Off';                    
                end
            end
        end
        
        
        function linkFilter = createPolarionLinkFilter(app)
            try 
                 if ~isempty(app.mgr.polarionAdapter)
                    
                    % Compose filter string
                     s = strings(0);
                     if app.polarionFilterUnresolvableCheckBox.Value
                         s(end+1) = 'AND';
                         s(end+1) = '~resolvable';
                     end
                     if app.polarionFilterUniDirectionalCheckBox.Value
                         s(end+1) = 'AND';
                         s(end+1) = '~bidirectional';
                     end
                     
                    if numel(s) > 0 && s(1) == "AND"
                        s = s(2:end);
                    end
                    
                    linkFilter = simpol.gui.LinkFilter(strjoin(s, " "));
                    
                 else
                    linkFilter = [];                
                 end  
             
            catch ME
                app.mgr.notifyError(ME.message);
            end             
        end
        
        
        function linkFilter = createMatlabLinkFilter(app)
            try
                if ~isempty(app.mgr.matlabAdapter)
               
                    % Compose filter string
                     s = strings(0);
                     if app.rmiFilterUnresolvableCheckBox.Value
                         s(end+1) = 'AND';
                         s(end+1) = '~resolvable';
                     end
                     if app.rmiFilterUniDirectionalCheckBox.Value
                         s(end+1) = 'AND';
                         s(end+1) = '~bidirectional';
                     end
                     if app.rmiFilterSuspectedCheckBox.Value
                         s(end+1) = 'AND';
                         s(end+1) = 'suspected';
                     end
                     
                    if numel(s) > 0 && s(1) == "AND"
                        s = s(2:end);
                    end
                    
                    linkFilter = simpol.gui.LinkFilter(strjoin(s, " "));
                else
                    linkFilter = [];                    
                end
                
            catch ME
                app.mgr.notifyError(ME.message);
            end            
        end
        
        function updateMatlabActions(app)
        
            cmdItems = {''};
            enabled = [];
        

            if app.matlabItemViewModel.isItemSelected() % If item is selected
                
                cmdItems = {'Item', 'Show', 'Uptrace'};
                enabled = true(2,1);
                
            elseif app.matlabItemViewModel.isLinkSelected()
                cmdItems = {'Link','Repair', 'Delete', 'Unsuspect'};
                
                enabled = true(3,1);
                
                if app.mgr.isReadOnly()
                    enabled(2:3) = true;
                end
                
                if ~app.mgr.linkTable.suspicionModel.allowsUnsuspect()
                    enabled(3) = false;
                end
            else
                % Use empty default
            end

            
             % Before creating new buttons, delete old ones
            children = app.RMIDataPanel.Children;
            for j=1:length(children)
                if strcmp(children(j).Tag, 'ActionButton')
                    delete(children(j))
                end
            end     
            
            % Create new buttons
            for i=2:length(cmdItems)
                 button = uibutton(app.RMIDataPanel, 'push',...
                    'Position',[1+(105*(i-2)) 42 100 22],'Text',cmdItems{i},...
                    'ButtonPushedFcn', @(Button,event) matlabActions_callback(app,cmdItems{1},cmdItems{i}),...
                    'Tag','ActionButton');
                
                    if enabled(i-1)
                        button.Enable = "on";
                    else
                        button.Enable = "off";
                    end
            end                
                         
        end
        
        
        function matlabActions_callback(app,selection, action)
       
            try
                
                % Switch between operations
                if(contains(selection, 'item','IgnoreCase',true))
                    
                    itemId = app.matlabItemViewModel.getSelectedItemId();
                    
                    switch(action)
                        case 'Show'
                            app.mgr.matlabAdapter.show(itemId);

                        case 'Uptrace'
                            item = app.mgr.matlabAdapter.getCachedItem(itemId);
                            links = app.mgr.linkTable.getLinksOfItem(...
                                simpol.SideType.MATLAB, itemId);
                            if ~isempty(item) && ~isempty(links)
                                s = strjoin([links.toItemId],",");
                                app.polarionItemSearchTxtField.Value = "id:" + s;
                                app.polarionItemFilter_changed();
                            else
                                app.mgr.notifyWarn("No uptrace found.");
                            end
                        otherwise
                            if startsWith(action, 'Link to')
                                app.createLink_clicked();
                            end                            
                    end
                else
                    
                    linkId = app.matlabItemViewModel.getSelectedLinkId();
                    [link, linkProps] = app.mgr.linkTable.getLink(simpol.SideType.MATLAB, linkId);
                    
                    if isempty(link)
                        app.mgr.notifyWarn("Link could not be found. Try to refresh.");
                    end
                    
                    itemId = link.itemId;
                     % If the item is not cached any more, throw an error.
                    if ~app.mgr.matlabAdapter.isCachedItem(itemId)
                        app.mgr.notifyWarn("Invalid link. Try to refresh and select a link.");
                        return;
                    end                    
                    
                    
                    switch(action)
                        case 'Delete'
                            
                            [bSuccess, bBiSuccess] = app.mgr.removeLink(...
                                simpol.SideType.MATLAB, linkId);                         
                            
                            if ~bSuccess
                                app.mgr.notifyWarn("Link could not be removed.");
                            end
                            
                            if ~bBiSuccess % Implies bSuccess
                                app.mgr.notifyWarn("Counter link could not be removed.");
                            end
                            
                            if isequal(app.AutoViewUpdateSwitch.Value, "On")
                                app.updateItemViews();
                            end
                            
                        case 'Repair'
                                
                            if ~isempty(link) && linkProps.Resolvable
                                app.mgr.addLink(link.toItemId, itemId);
                            else
                                app.mgr.notifyWarn('Link must be resolvable to repair.');
                            end
                            
                            app.updateItemViews();
                            
                        case 'Unsuspect'
                            app.mgr.unsuspectLink(simpol.SideType.MATLAB, linkId);
                            app.updateItemViews();                        
                    end
                end
                
            catch ME
                app.mgr.notifyError(ME.message);
            end            
            
        end
        
        
        function updatePolarionActions(app)
          
            if app.mgr.isReadOnly()
                if app.polarionItemViewModel.isItemSelected() % If item is selected
                       
                    cmdItems = {'Item', 'Show', 'Downtrace'};
                end
            else
                if app.polarionItemViewModel.isItemSelected() % If item is selected
                    cmdItems = {'Item','Show', 'Downtrace'};
                                       
                elseif app.polarionItemViewModel.isLinkSelected()
                    cmdItems = {'Link','Repair', 'Delete'};
                     if app.mgr.linkTable.suspicionModel.allowsUnsuspect()
                        cmdItems{end+1} = 'Unsuspect';
                    end
                else
                    cmdItems = {'',''};
                end
            end
                
            % Before creating new button, delete old ones
            children=app.PolarionDataPanel.Children;
            for j=1:length(children)
                if strcmp(children(j).Tag,'ActionButton')
                    delete(children(j))
                end
            end
            
            % Create new buttons
            for i=2:length(cmdItems)
                uibutton(app.PolarionDataPanel, 'push',...
                    'Position',[1+(105*(i-2)) 42 100 22],'Text',cmdItems{i},...
                    'ButtonPushedFcn',@(Button,event) polarionActions_callback(...
                        app, cmdItems{1}, cmdItems{i}),...
                    "Tag",'ActionButton');
            end                
                
        end
        
        
        function polarionActions_callback(app,selection, action)
            
            try                               
                
                % Switch between operations
                if(contains(selection, 'item','IgnoreCase',true))
                    
                    itemId = app.polarionItemViewModel.getSelectedItemId();
                    
                    switch(action)
                        case 'Show'
                            app.mgr.polarionAdapter.show(itemId);
                            
                        case 'Downtrace'
                            item = app.mgr.polarionAdapter.getCachedItem(itemId);
                            [links, linkProps] = app.mgr.linkTable.queryLinks(...
                                simpol.SideType.POLARION, itemId, "Resolvable");
                            if ~isempty(item) && ~isempty(links)
                                s = strjoin(linkProps.ResolvedItemId,",");
                                app.matlabItemSearchTxtField.Value = "id:" + s;
                                app.matlabItemFilter_changed();
                            else
                                app.mgr.notifyWarn("No uptrace found.");
                            end
                        otherwise
                            if startsWith(action, 'Link to','IgnoreCase',true)
                                app.createLink_clicked();
                            end
                    end
                else % Link
                    
                    linkId = app.polarionItemViewModel.getSelectedLinkId();
                    if isempty(linkId)
                        app.mgr.notifyWarn("Link could not be found. Try to refresh.");
                        return;
                    end
                    
                    switch(action)
                        
                        case 'Delete'                       
                            
                            % Delete action for links.                    
                            [bSuccess, bBiSuccess] =...
                                app.mgr.removeLink(simpol.SideType.POLARION, linkId);
 
                            % In case of sucess, dirty items
                            if ~bSuccess
                                app.mgr.notifyWarn("Link could not be removed.");                  
                            end
                            
                            if ~bBiSuccess
                               app.mgr.notifyWarn("Counter link could not be removed.");
                            end
                            
                            if isequal(app.AutoViewUpdateSwitch.Value, "On")
                                app.updateItemViews();
                            end
                            
                        case 'Repair'
                            % Repair a link
                            
                            % Get the link
                            [link, linkProps] = app.mgr.linkTable.getLink(...
                                simpol.SideType.POLARION, linkId);
                                               
                            % If the link cannot be obtained, do nothing
                            if ~isempty(link) && linkProps.Resolvable(1)
                                
                                app.mgr.addLink(link.itemId, link.toItemId);
                                
                                app.updateItemViews();
                            else
                                app.mgr.notifyWarn("Link must be resolvable to repair.");
                            end
                    end
                end
                
            catch ME
                app.mgr.notifyError(ME.message);
            end            
                        
        end
        
        
    end
    
    methods(Access=public)
        
        function timer_callback(app, t, e)
            
            % There are various criteria, when the callback should do nothing...
            if ~isvalid(app)
                return;
            end
            
            if strcmp(app.UIFigure.Visible, 'off')
                return;
            end
                        
            if isempty(app.mgr.matlabAdapter) || ~isvalid(app.mgr.matlabAdapter)
                return;
            end
            
            if app.MatlabSyncSelectionSwitch.Value == "Off"
                return;
            end
            
            % Get selected
            extSelectedIds = app.mgr.matlabAdapter.getSelectedItems(app.mgr.settings.Targets);
                            
            % Only those, which are cached
            if ~isempty(extSelectedIds)
                  extSelectedIds = extSelectedIds(app.mgr.matlabAdapter.isCachedItem(extSelectedIds));
            end
            
            
            % Only update UI, if the tacked items changed
            if numel(app.externalSelectedMatlabIds) ~= numel(extSelectedIds) ||...
                        numel(union(app.externalSelectedMatlabIds, extSelectedIds)) ~=...
                        numel(extSelectedIds)
                    app.externalSelectedMatlabIds = extSelectedIds;

                    app.matlabItemSearchTxtField.Value = "id:" + strjoin(extSelectedIds, ",");
                    app.matlabItemSearchTxtField.ValueChangedFcn(app, []);
                    
                    app.updateItemViews();
            end
        end
        
        function close(app)
             app.UIFigure.Visible = 'off';
        end
        
        
        function singleSideView(app, bSingleSide)
            try
                if bSingleSide
                    
                    screenSize = get(0, 'ScreenSize');
                    
                    pos = app.UIFigure.Position;
                    pos(1) = 0;
                    pos(2) = screenSize(4)/2 - pos(4)/2 - 25; % 25 For window header
                    pos(3) = 640;
                    app.UIFigure.Position = pos;
                    
                    app.BothSideViewButton.Value = true;
                    
                    % Move create button and item labels
                    pos = [51    56   521    23];
                    app.SelectedRMIItemLabel.Position = pos;
                    app.SelectedRMIItemLabel.HorizontalAlignment = 'right';
                    
                    
                    % If a model is selected, open it side by side
                    if ~isempty(app.mgr) && ~isempty(app.mgr.settings) && ...
                            strcmp(app.mgr.settings.TargetType, 'simpol.adapter.RMISimulinkAdapter')
                        
                        % Select model object
                        if app.mgr.matlabAdapter.isTargetAvailable(app.ItemFilterDropDown.Value)
                            o = get_param(app.ItemFilterDropDown.Value, 'object');
                        else
                            o = [];
                        end
                        
                        if ~isempty(o)
                            open_system(o.Path);
                            if o.isLibrary
                                o.Lock = 'off';
                            end
                            set_param(gcs, 'Location', [640, 0, screenSize(3), screenSize(4)-35]); % 35 for windows toolbar
                        end
                        
                        % If selection mode is provided, set checkbox
                        if app.mgr.matlabAdapter.providesSelection() && ...
                                app.MatlabSyncSelectionSwitch.Value == "Off"
                            app.MatlabSyncSelectionSwitch.Value = "On";
                            app.matlabSyncSelection_changed([]);
                            
                        end                        
                    end

                else
                    % Update figure width
                    pos = app.UIFigure.Position;
                    pos(3) = 1200;
                    app.UIFigure.Position = pos;
                                                  
                    app.BothSideViewButton.Value = false;
                    
                    pos = [651    76   521    23];
                    app.SelectedRMIItemLabel.Position = pos;
                    app.SelectedRMIItemLabel.HorizontalAlignment = 'left';
                end
                
                app.bSingleSideView = bSingleSide;
                
            catch ME
                app.mgr.notifyError(ME.message);
            end
        end
        
        function clear_gui(app)
            
            try    
                app.refreshGuiPolarionSelectionLabels();
            catch ME
                app.mgr.notifyError(ME.message);
            end            
            
        end


        function reload(app, varargin)

            try
                
                h = [];                
            
                p = inputParser;
                p.addParameter('allocation', '', @ischar);
                
                p.parse(varargin{:});
                      
                
                app.clear_gui();
                
                if ~isempty(p.Results.allocation)
                    app.mgr.setAllocationFile(p.Results.allocation);
                end
                app.externalSelectedMatlabIds = [];
                
                app.polarionItemViewModel.setAdapter(app.mgr.polarionAdapter);
                app.matlabItemViewModel.setAdapter(app.mgr.matlabAdapter);
                
                % Load GUI
                app.load_gui_filter();
                
                % 1st-time refresh of GUI
                app.refresh_gui_filter();
                app.refreshGuiControlbar();
                
                
                % If some important settings are missing, throw a information alert
                if ~isempty(app.mgr.polarionAdapter.sServerURL) && ...
                        ~isempty(app.mgr.polarionAdapter.sProjectID)
                    
                    h = uiprogressdlg(app.UIFigure);       
                    
                    % --------------------------------
                    h.Message = "Caching Polarion work items...";
                    h.Value = 0.0;
                    app.mgr.polarionAdapter.updateCache();
                    
                    % --------------------------------
                    h.Message = "Caching MATLAB items...";
                    h.Value = 0.45;
                    app.mgr.matlabAdapter.updateCache();
                    
                    % --------------------------------
                    h.Message = "Initializing views...";
                    h.Value = 0.9;
                    app.refresh_gui_filter();
                    app.refreshGuiControlbar();                   
                    app.polarionViewFilterChanged = true;
                    app.matlabViewFilterChanged = true;
                    
                    h.close();
                    
                    app.updateItemViews();
                    
                end             
            catch ME
                if ~isempty(h)
                    h.close();
                end
                app.mgr.notifyError(ME.message);
            end
  
        end
        
        
        function deleteApp(app)
             if ~isempty(app.settingsDialog)
                 delete(app.settingsDialog);
             end
             delete(app);
        end
        
        
        function polarionItemId = getSelectedPolarionItemId(app)
            % getSelectedPolarionItemId Returns the ID of the currently
            % selected Polarion item. Needed for establishing links
            % externally ("LinkToSelection").
            
            polarionItemId = app.polarionItemViewModel.getSelectedItemId();
        end
        
        
        function updateItemViews(app)
        
            hProgressDlg = uiprogressdlg(app.UIFigure,...
                "Indeterminate", "on", "Title", "Updating views.."); 
        
            try
        
                % If any of the adapters is missing or invalid, do not update
                if isempty(app.mgr.polarionAdapter) || ~isvalid(app.mgr.polarionAdapter) || ...
                    isempty(app.mgr.matlabAdapter) || ~isvalid(app.mgr.matlabAdapter) 
                    return;
                end
            
                % Conditionally update link table (if item cache changed)
                % ------------------------------------------------------
                
                app.mgr.linkTable.update(app.mgr.polarionAdapter, app.mgr.matlabAdapter);
                
                % Create Polarion side link & item filter and set in view model
                % ------------------------------------------------------     
                
                if app.polarionViewFilterChanged
                    
                    % Create link & item filter and set in view model
               
                    if app.polarionItemSearchTxtField.Value == app.defaultSearchText
                        searchString = "";
                    else
                        searchString = app.polarionItemSearchTxtField.Value ;
                    end
                    
                    itemFilter = simpol.gui.ItemFilter([], ...
                                'SearchString', searchString,...
                                'LinkStatus', app.polarionItemFilterDropDown.Value);  
                    app.polarionItemViewModel.setItemFilter(itemFilter);
                    
                    linkFilter = app.createPolarionLinkFilter();
                    app.polarionItemViewModel.setLinkFilter(linkFilter);
                    
                    app.polarionViewFilterChanged = false;
                end
                
                % Create MATLAB side link & item filter and set in view model
                % ------------------------------------------------------
                
                if app.matlabViewFilterChanged
                    
                     if app.matlabItemSearchTxtField.Value == app.defaultSearchText
                        searchString = "";
                    else
                        searchString = app.matlabItemSearchTxtField.Value ;
                    end               
                    
                     itemFilter = simpol.gui.ItemFilter(...
                                   app.ItemFilterDropDown.Value,...
                                   'SearchString', searchString,...
                                   'LinkStatus', app.rmiFilterItemLinkStatusDropDown.Value);
                     app.matlabItemViewModel.setItemFilter(itemFilter);
                     
                 
                     linkFilter = app.createMatlabLinkFilter();
                     app.matlabItemViewModel.setLinkFilter(linkFilter);
                    
                     app.matlabViewFilterChanged = false;
                end
                
                % Conditionally update the view model (if view model changed)
                % ------------------------------------------------------           
                
                app.polarionItemViewModel.updateViewModel();
                app.matlabItemViewModel.updateViewModel();
                
                hProgressDlg.close();
                
            catch ME
                hProgressDlg.close();
                app.mgr.notifyError(ME.message);
            end
        
        end
        
        function releaseModalLock(app)
            % Release the fake modal lock of the app. Destroying
            % the lock removes the overlay.
            if ~isempty(app.modalLock) && isvalid(app.modalLock)
                delete(app.modalLock);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function window_startup(app)
          
            try            
            
                % Load Manager
                app.mgr = simpol.Manager;
                if isnumeric(app.mgr.hSimPolGUI)&& app.mgr.hSimPolGUI ~= -1 && (app.mgr.hSimPolGUI ~= app)
                    app.mgr.close();
                end
                app.mgr.hSimPolGUI = app;
                
                app.mgrNotifyListener = listener(app.mgr, 'UserNotificationEvent',...
                    @(source, eventData) app.notifyUser(eventData));
                
                app.singleSideView(false);
                
                % Refresh build
                app.BuildLabel.Text = ['Version ' app.mgr.version ', Build ' app.mgr.build];
                
                app.matlabItemViewModel = simpol.gui.TreeItemViewModel(...
                    [], app.mgr.linkTable, app.MatlabItemTree);
                app.polarionItemViewModel = simpol.gui.TreeItemViewModel(...
                    [], app.mgr.linkTable, app.PolarionItemTree);

                
                % Refresh control bar
                app.refreshGuiControlbar();
                app.refresh_gui_filter();           
                
                % Create timer to watch Simulink
                app.mgr.guiUpdateTimer = timer;
                app.mgr.guiUpdateTimer.Period = 0.5;
                app.mgr.guiUpdateTimer.TimerFcn = @app.timer_callback;
                app.mgr.guiUpdateTimer.ExecutionMode = 'fixedRate';
                app.mgr.guiUpdateTimer.UserData = app;
              
            catch ME
                app.mgr.notifyError(ME.message);
            end            
            
        end

        % Button pushed function: SettingsButton
        function settings_clicked(app, event)
            try
                app.modalLock = simpol.gui.ModalLock(app.UIFigure);
                SimPol_Settings(app);
            catch ME
                app.releaseModalLock();
                app.mgr.notifyError(ME.message);
            end                
        end

        % Button pushed function: CloseButton
        function close_clicked(app, event)
            msg = "Do you really want to shut down SimPol?";
            title = "Confirm Shutdown";
            selection = uiconfirm(app.UIFigure, msg, title,...
             'Options',{'Hide', 'Shutdown', 'Cancel'},...
             'DefaultOption', 1, 'CancelOption',3);
         
            if selection == "Hide"
                app.UIFigure.Visible = 'off';
            elseif selection == "Shutdown"
                evalin('base', 'SimPol close');
            else 
                % Cancel - do nothing
            end            
        end

        % Button pushed function: UpdatePolarion
        function updatePolarionCache_clicked(app, event)
            
           if isempty(app.mgr.polarionAdapter)
               return;
           end
        
           hProgressDlg = uiprogressdlg(app.UIFigure,...
                "Indeterminate", "on", "Title", "Caching Polarion items..."); 
            try
                event.Source.Enable = 'off';
                app.mgr.polarionAdapter.updateCache();
                app.updateItemViews();
                event.Source.Enable = 'on';
                hProgressDlg.close();
            catch ME
                event.Source.Enable = 'on';
                hProgressDlg.close();
                app.mgr.notifyError(ME.message);
            end                
        end

        % Button pushed function: CreateLinkbutton
        function createLink_clicked(app, event)
            try
                itemId_p = app.polarionItemViewModel.getSelectedItemId();
                itemId_m = app.matlabItemViewModel.getSelectedItemId();
                
                if isempty(itemId_p) || isempty(itemId_m)
                    app.mgr.notifyError("Cannot create link. Less then one item selected.");
                end
                       
                app.mgr.addLink(itemId_p, itemId_m);

                if isequal(app.AutoViewUpdateSwitch.Value, "On")
                    app.updateItemViews();
                end
                
            catch ME
                app.mgr.notifyError(ME.message);
            end
        end

        % Value changed function: ItemFilterDropDown, 
        % matlabItemSearchTxtField
        function matlabItemFilter_changed(app, event)
            app.matlabViewFilterChanged = true;
            app.updateItemViews();
        end

        % Close request function: UIFigure
        function window_close(app, event)
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: UpdateTargets
        function updateMatlabCache_clicked(app, event)
            
            if isempty(app.mgr.matlabAdapter)
                return;
            end
        
            hProgressDlg = uiprogressdlg(app.UIFigure,...
                "Indeterminate", "on", "Title", "Caching MATLAB items..."); 
            try
                event.Source.Enable = 'off';
                
                app.mgr.matlabAdapter.updateCache();
                app.updateItemViews();
                
                event.Source.Enable = 'on';
                hProgressDlg.close();
            catch ME
                event.Source.Enable = 'on';
                hProgressDlg.close();
                app.mgr.notifyError(ME.message);
            end
        end

        % Value changed function: BothSideViewButton
        function view_changed(app, event)
            try
                value = app.BothSideViewButton.Value;
            
                if value
                    app.singleSideView(true);
                else
                    app.singleSideView(false);
                end
            catch ME
                app.mgr.notifyError(ME.message);
            end
        end

        % Value changed function: 
        % polarionFilterUniDirectionalCheckBox, 
        % polarionFilterUnresolvableCheckBox
        function polarionLinkFilter_changed(app, event)
            app.polarionViewFilterChanged = true;
            app.updateItemViews();
        end

        % Value changed function: rmiFilterItemLinkStatusDropDown, 
        % rmiFilterSuspectedCheckBox, 
        % rmiFilterUniDirectionalCheckBox, 
        % rmiFilterUnresolvableCheckBox
        function matlabLinkFilter_changed(app, event)
            app.matlabViewFilterChanged = true;
            app.updateItemViews();
        end

        % Value changed function: polarionItemFilterDropDown, 
        % polarionItemSearchTxtField
        function polarionItemFilter_changed(app, event)
            app.polarionViewFilterChanged = true;
            app.updateItemViews();
        end

        % Button pushed function: PatchButton
        function patch_clicked(app, event)
            try
                app.modalLock = simpol.gui.ModalLock(app.UIFigure);
                SimPol_Maintenance(app);
            catch ME
                app.releaseModalLock();
                app.mgr.notifyError(ME.message);
            end             
        end

        % Button pushed function: NewAllocationFileButton
        function new_allocationfile_clicked(app, event)
            try
                
                [filename, pathname] = uiputfile({'*.spa'}, 'Create new allocation file...');
                
                if ~isnumeric(filename) && ~isnumeric(pathname)
                    
                    app.reload('allocation', fullfile(pathname, filename));
                    
                    app.settings_clicked();
                end
                
            catch ME
                app.mgr.notifyError(ME.message);
            end            
        end

        % Button pushed function: OpenAllocationFileButton
        function open_allocationfile_clicked(app, event)

            try
                
                if ispref('SimPol', 'LastAllocationFileLocation')
                    
                    % Open with last location as default
                    [filename, pathname] = uigetfile(...
                        getpref('SimPol', 'LastAllocationFileLocation'),...
                         'Open Allocation File...');
                else
                    [filename, pathname] = uigetfile({'*.spa'}, 'Open Allocation File...');
                end
                
                if ~isnumeric(filename) && ~isnumeric(pathname)
                    setpref('SimPol', 'LastAllocationFileLocation', fullfile(pathname, filename));
                    app.reload('allocation', fullfile(pathname, filename));
                else
                    
                end
                
            catch ME
                app.mgr.notifyError(ME.message);
            end            
        end

        % Callback function
        function logo_clicked(app, event)
            web('http://www.fsd.mw.tum.de');
        end

        % Button pushed function: HelpButton
        function help_clicked(app, event)
            open('SimPol User Guide.pdf');
        end

        % Value changed function: MatlabSyncSelectionSwitch
        function matlabSyncSelection_changed(app, event)
            
            % Start/stop timer callback
            if app.MatlabSyncSelectionSwitch.Value == "On"
                % Ensure that tracker is running
                if ~strcmp(app.mgr.guiUpdateTimer.Running, 'on')
                    start(app.mgr.guiUpdateTimer);
                    app.externalSelectedMatlabIds = [];
                end
            else
                % Ensure that tracker is stopped
                if ~strcmp(app.mgr.guiUpdateTimer.Running, 'off')
                    stop(app.mgr.guiUpdateTimer);
                end
            end
            
            app.matlabItemSearchTxtField.Value = "";
            app.refresh_gui_filter();
            app.matlabViewFilterChanged = true;
            app.updateItemViews();
        end

        % Selection changed function: PolarionItemTree
        function polarionItemTree_selectionChanged(app, event)
            app.polarionItemViewModel.selectionChanged(event); 
            app.updatePolarionActions();
            app.refreshGuiCreateLinkButton();
        end

        % Selection changed function: MatlabItemTree
        function matlabItemTree_selectionChanged(app, event)
            app.matlabItemViewModel.selectionChanged(event);
            app.updateMatlabActions();
            app.refreshGuiCreateLinkButton();            
        end

        % Value changed function: AutoViewUpdateSwitch
        function AutoViewUpdateSwitchValueChanged(app, event)
            app.updateItemViews();
            if isequal(app.AutoViewUpdateSwitch.Value, "Off")
                app.RefreshmanuallytoseechangesLabel.Visible = true;
            else
                app.RefreshmanuallytoseechangesLabel.Visible = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SimPol';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @window_close, true);
            app.RMIDataPanel = uipanel(app.UIFigure);
            app.RMIDataPanel.Title = 'MATLAB';
            app.UpdateTargets = uibutton(app.RMIDataPanel, 'push');
            app.UpdateTargets.ButtonPushedFcn = createCallbackFcn(app, @updateMatlabCache_clicked, true);
            app.UpdateTargets.Icon = 'simpol_reload.png';
            app.UpdateTargets.Tooltip = {'Update RMI Cache'};
            app.UpdateTargets.Text = '';
            app.ItemFilterDropDownLabel = uilabel(app.RMIDataPanel);
            app.ItemFilterDropDownLabel.Text = 'Item Filter:';
            app.ItemFilterDropDown = uidropdown(app.RMIDataPanel);
            app.ItemFilterDropDown.Items = {};
            app.ItemFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @matlabItemFilter_changed, true);
            app.ItemFilterDropDown.Value = {};
            app.LinkFilterLabel_2 = uilabel(app.RMIDataPanel);
            app.LinkFilterLabel_2.Text = 'Link Filter:';
            app.rmiFilterUnresolvableCheckBox = uicheckbox(app.RMIDataPanel);
            app.rmiFilterUnresolvableCheckBox.ValueChangedFcn = createCallbackFcn(app, @matlabLinkFilter_changed, true);
            app.rmiFilterUnresolvableCheckBox.Text = 'Unresolvable';
            app.rmiFilterSuspectedCheckBox = uicheckbox(app.RMIDataPanel);
            app.rmiFilterSuspectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @matlabLinkFilter_changed, true);
            app.rmiFilterSuspectedCheckBox.Text = 'Suspected';
            app.rmiFilterUniDirectionalCheckBox = uicheckbox(app.RMIDataPanel);
            app.rmiFilterUniDirectionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @matlabLinkFilter_changed, true);
            app.rmiFilterUniDirectionalCheckBox.Text = 'Uni-directional';
            app.rmiFilterItemLinkStatusDropDown = uidropdown(app.RMIDataPanel);
            app.rmiFilterItemLinkStatusDropDown.Items = {'All', 'Unlinked Only', 'Linked Only'};
            app.rmiFilterItemLinkStatusDropDown.ValueChangedFcn = createCallbackFcn(app, @matlabLinkFilter_changed, true);
            app.rmiFilterItemLinkStatusDropDown.Value = 'All';
            app.SelectedRMIItemLabel = uilabel(app.RMIDataPanel);
            app.SelectedRMIItemLabel.Text = '';
            app.MatlabItemTree = uitree(app.RMIDataPanel);
            app.MatlabItemTree.SelectionChangedFcn = createCallbackFcn(app, @matlabItemTree_selectionChanged, true);
            app.SynchronizeselectionwithsourcetoolSwitchLabel = uilabel(app.RMIDataPanel);
            app.SynchronizeselectionwithsourcetoolSwitchLabel.Text = 'Synchronize selection with source tool';
            app.MatlabSyncSelectionSwitch = uiswitch(app.RMIDataPanel, 'slider');
            app.MatlabSyncSelectionSwitch.ValueChangedFcn = createCallbackFcn(app, @matlabSyncSelection_changed, true);
            app.matlabItemSearchTxtField = uieditfield(app.RMIDataPanel, 'text');
            app.matlabItemSearchTxtField.ValueChangedFcn = createCallbackFcn(app, @matlabItemFilter_changed, true);
            app.PolarionDataPanel = uipanel(app.UIFigure);
            app.PolarionDataPanel.Title = 'Polarion';
            app.ItemFilterLabel = uilabel(app.PolarionDataPanel);
            app.ItemFilterLabel.Text = 'Item Filter:';
            app.polarionItemSearchTxtField = uieditfield(app.PolarionDataPanel, 'text');
            app.polarionItemSearchTxtField.ValueChangedFcn = createCallbackFcn(app, @polarionItemFilter_changed, true);
            app.UpdatePolarion = uibutton(app.PolarionDataPanel, 'push');
            app.UpdatePolarion.ButtonPushedFcn = createCallbackFcn(app, @updatePolarionCache_clicked, true);
            app.UpdatePolarion.Icon = 'simpol_reload.png';
            app.UpdatePolarion.Tooltip = {'Update Polarion Cache'};
            app.UpdatePolarion.Text = '';
            app.polarionFilterUnresolvableCheckBox = uicheckbox(app.PolarionDataPanel);
            app.polarionFilterUnresolvableCheckBox.ValueChangedFcn = createCallbackFcn(app, @polarionLinkFilter_changed, true);
            app.polarionFilterUnresolvableCheckBox.Text = 'Unresolvable';
            app.polarionFilterUniDirectionalCheckBox = uicheckbox(app.PolarionDataPanel);
            app.polarionFilterUniDirectionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @polarionLinkFilter_changed, true);
            app.polarionFilterUniDirectionalCheckBox.Text = 'Uni-directional';
            app.LinkFilterLabel = uilabel(app.PolarionDataPanel);
            app.LinkFilterLabel.Text = 'Link Filter:';
            app.polarionItemFilterDropDown = uidropdown(app.PolarionDataPanel);
            app.polarionItemFilterDropDown.Items = {'All', 'Unlinked Only', 'Linked Only'};
            app.polarionItemFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @polarionItemFilter_changed, true);
            app.polarionItemFilterDropDown.Value = 'All';
            app.PolarionActionLabel = uilabel(app.PolarionDataPanel);
            app.PolarionActionLabel.Text = '';
            app.SelectedPolarionItemLabel = uilabel(app.PolarionDataPanel);
            app.SelectedPolarionItemLabel.Text = '';
            app.PolarionItemTree = uitree(app.PolarionDataPanel);
            app.PolarionItemTree.SelectionChangedFcn = createCallbackFcn(app, @polarionItemTree_selectionChanged, true);
            app.AutoViewUpdateSwitchLabel = uilabel(app.PolarionDataPanel);
            app.AutoViewUpdateSwitchLabel.Text = 'Auto View Update';
            app.AutoViewUpdateSwitch = uiswitch(app.PolarionDataPanel, 'slider');
            app.AutoViewUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoViewUpdateSwitchValueChanged, true);
            app.AutoViewUpdateSwitch.Value = 'On';
            app.RefreshmanuallytoseechangesLabel = uilabel(app.PolarionDataPanel);
            app.RefreshmanuallytoseechangesLabel.Visible = 'off';
            app.RefreshmanuallytoseechangesLabel.Text = 'Refresh manually to see changes';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = ' ';
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @close_clicked, true);
            app.CloseButton.Text = 'Close';
            app.StatusLabel = uilabel(app.Panel);
            app.StatusLabel.Text = 'Status';
            app.CopyrightLabel = uilabel(app.Panel);
            app.CopyrightLabel.Text = '© M.Hochstrasser, TUM-FSD, 2021';
            app.HelpButton = uibutton(app.Panel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @help_clicked, true);
            app.HelpButton.Text = 'Help';
            app.BuildLabel = uilabel(app.Panel);
            app.BuildLabel.Text = 'build no';
            app.SettingsButton = uibutton(app.UIFigure, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @settings_clicked, true);
            app.SettingsButton.Icon = 'simpol_settings.png';
            app.SettingsButton.Tooltip = {'Edit Allocation File'};
            app.SettingsButton.Text = '';
            app.CreateLinkbutton = uibutton(app.UIFigure, 'push');
            app.CreateLinkbutton.ButtonPushedFcn = createCallbackFcn(app, @createLink_clicked, true);
            app.CreateLinkbutton.Icon = 'simpol_link.png';
            app.CreateLinkbutton.Enable = 'off';
            app.CreateLinkbutton.Tooltip = {'Create New Link'};
            app.CreateLinkbutton.Text = '';
            app.BothSideViewButton = uibutton(app.UIFigure, 'state');
            app.BothSideViewButton.ValueChangedFcn = createCallbackFcn(app, @view_changed, true);
            app.BothSideViewButton.Tooltip = {'Show Polarion links side by side with source tool'};
            app.BothSideViewButton.Icon = 'simpol_view.png';
            app.BothSideViewButton.Text = '';
            app.BothSideViewButton.Value = true;
            app.NewAllocationFileButton = uibutton(app.UIFigure, 'push');
            app.NewAllocationFileButton.ButtonPushedFcn = createCallbackFcn(app, @new_allocationfile_clicked, true);
            app.NewAllocationFileButton.Icon = 'simpol_settings_new.png';
            app.NewAllocationFileButton.Tooltip = {'New Allocation File'};
            app.NewAllocationFileButton.Text = '';
            app.OpenAllocationFileButton = uibutton(app.UIFigure, 'push');
            app.OpenAllocationFileButton.ButtonPushedFcn = createCallbackFcn(app, @open_allocationfile_clicked, true);
            app.OpenAllocationFileButton.Icon = 'simpol_settings_open.png';
            app.OpenAllocationFileButton.Tooltip = {'Load Allocation File'};
            app.OpenAllocationFileButton.Text = '';
            app.AllocationFileLabel = uilabel(app.UIFigure);
            app.AllocationFileLabel.Text = 'Allocation File';
            app.AllocationFileValue = uilabel(app.UIFigure);
            app.AllocationFileValue.Text = '<no allocation file>';
            app.PolarionBaselineLabel = uilabel(app.UIFigure);
            app.PolarionBaselineLabel.Text = 'Baseline';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'simpol_logo_small.png';
            app.FSD_Logo = uiimage(app.UIFigure);
            app.FSD_Logo.ImageSource = 'fsd_logo.png';
            app.PatchButton = uibutton(app.UIFigure, 'push');
            app.PatchButton.ButtonPushedFcn = createCallbackFcn(app, @patch_clicked, true);
            app.PatchButton.Icon = 'simpol_maintain.png';
            app.PatchButton.Tooltip = {'Repair links'};
            app.PatchButton.Text = '';
            app.CreatelinkLabel = uilabel(app.UIFigure);
            app.CreatelinkLabel.Text = 'Create link';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimPolManager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @window_startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end