classdef SimPol_Maintenance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaintenanceCenterUIFigure    matlab.ui.Figure
        PolarionLinkStatusPanel      matlab.ui.container.Panel
        PolarionStatPlot             matlab.ui.control.UIAxes
        MATLABLinkStatusPanel        matlab.ui.container.Panel
        MATLABStatPlot               matlab.ui.control.UIAxes
        PatchActionsPanel            matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        FcnDescription               matlab.ui.control.TextArea
        RunButton                    matlab.ui.control.Button
        MaintenanceFunctionsListBox  matlab.ui.control.ListBox
    end


    properties (Access = private)
        jobs; % Map of available patch jobs
        mgr % SimPol Manager instance
        simpolApp % Handle of the main SimPol instance (app)
        progressDialog % Job progress dialog
    end

    methods (Access = private)
    
        function plotStatistics(app, uicomponent, side)
  
            % Get data for all links in link table
            linkTable = app.mgr.linkTable;
            linkProps = linkTable.getAllLinks(side);
            totalLinks = size(linkProps, 1);
            if totalLinks > 0
                unresolvableLinks = totalLinks - sum(linkProps.Resolvable);
                uniDirectionalLinks = totalLinks - sum(linkProps.BiDirectional);
                suspectedLinks = sum(linkProps.Suspected);
            else
                unresolvableLinks = 0;
                uniDirectionalLinks = 0;
                suspectedLinks = 0;
            end
                      
            values = diag([totalLinks, unresolvableLinks,uniDirectionalLinks, suspectedLinks]);
            
            h = bar(uicomponent, values, .5, 'stacked','EdgeColor', 'none');
            uicomponent.FontSize = 9;
            uicomponent.XLim = [.5, 4.5];
            uicomponent.XTickLabel = {'Total', 'Unresolvable', 'Uni-Dir.', 'Suspected'};
            
            c = lines();
            for i = 1:numel(h)
                h(i).FaceColor = c(i,:);
            end            
        end
        
        function refresh(app)
            app.plotStatistics(app.PolarionStatPlot, simpol.SideType.POLARION);
            app.plotStatistics(app.MATLABStatPlot, simpol.SideType.MATLAB);                               
        end
        
        
        function handleProgressEvent(app, event)
            if ~isempty(app.progressDialog) && isvalid(app.progressDialog)
                app.progressDialog.Message = event.message;
                app.progressDialog.Value = event.progress;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simpolApp)
            
            app.mgr = SimPol('instance');
            app.simpolApp = simpolApp;
            
            app.jobs = containers.Map;
            
            app.jobs(simpol.maintenance.RepairUniDirectional.getName()) ...
                = simpol.maintenance.RepairUniDirectional();
            
            if strcmp(app.mgr.settings.LinkModel, 'Surrogate Linking')
                app.jobs(simpol.maintenance.SyncSurrogateWorkItems.getName()) ...
                    = simpol.maintenance.SyncSurrogateWorkItems();
            end
            
            if strcmp(app.mgr.settings.SuspicionModel, 'Revision')
                app.jobs(simpol.maintenance.UnsuspectAll.getName()) ...
                    = simpol.maintenance.UnsuspectAll();
            end
            
            if app.mgr.settings.PushImage
                app.jobs(simpol.maintenance.UpdateImages.getName()) ...
                    = simpol.maintenance.UpdateImages();
            end
            
            app.MaintenanceFunctionsListBox.Items = app.jobs.keys();
            
            app.refresh();
        end

        % Value changed function: MaintenanceFunctionsListBox
        function fcn_changed(app, event)
            value = app.MaintenanceFunctionsListBox.Value;
            app.Panel.Title = value;
            app.FcnDescription.Value = app.jobs(value).getDescription();
        end

        % Button pushed function: RunButton
        function run_clicked(app, event)
            app.progressDialog = uiprogressdlg(app.MaintenanceCenterUIFigure);    
            try
                job = app.jobs(app.MaintenanceFunctionsListBox.Value);
                
                l = listener(job, 'JobProgressEvent',...
                    @(source, eventData) app.handleProgressEvent(eventData));
                job.execute();
                
                app.simpolApp.updateItemViews();
                
                app.refresh();
                app.progressDialog.close();
                app.progressDialog = [];               

            catch ME
                app.progressDialog.close();
                app.progressDialog = [];
                uialert(app.MaintenanceCenterUIFigure, ME.message,...
                    "Warning", "Icon", "warning");
            end
        end

        % Close request function: MaintenanceCenterUIFigure
        function shutdownFcn(app, event)
            app.simpolApp.releaseModalLock();
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaintenanceCenterUIFigure = uifigure('Visible', 'off');
            app.MaintenanceCenterUIFigure.Name = 'Maintenance Center';
            app.MaintenanceCenterUIFigure.CloseRequestFcn = createCallbackFcn(app, @shutdownFcn, true);
            app.PolarionLinkStatusPanel = uipanel(app.MaintenanceCenterUIFigure);
            app.PolarionLinkStatusPanel.Title = 'Polarion Link Status';
            app.PolarionStatPlot = uiaxes(app.PolarionLinkStatusPanel);
            app.MATLABLinkStatusPanel = uipanel(app.MaintenanceCenterUIFigure);
            app.MATLABLinkStatusPanel.Title = 'MATLAB Link Status';
            app.MATLABStatPlot = uiaxes(app.MATLABLinkStatusPanel);
            app.PatchActionsPanel = uipanel(app.MaintenanceCenterUIFigure);
            app.PatchActionsPanel.Title = 'Patch Actions';
            app.Panel = uipanel(app.PatchActionsPanel);
            app.FcnDescription = uitextarea(app.Panel);
            app.FcnDescription.Editable = 'off';
            app.RunButton = uibutton(app.PatchActionsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run_clicked, true);
            app.RunButton.Text = 'Run';
            app.MaintenanceFunctionsListBox = uilistbox(app.PatchActionsPanel);
            app.MaintenanceFunctionsListBox.Items = {};
            app.MaintenanceFunctionsListBox.ValueChangedFcn = createCallbackFcn(app, @fcn_changed, true);
            app.MaintenanceFunctionsListBox.Value = {};
            % Show the figure after all components are created
            app.MaintenanceCenterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimPol_Maintenance(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaintenanceCenterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaintenanceCenterUIFigure)
        end
    end
end