classdef SimPol_Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimPolSettingsUIFigure          matlab.ui.Figure
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        TabAdapter                      matlab.ui.container.TabGroup
        PolarionTab                     matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        ServerTab                       matlab.ui.container.Tab
        PolarionServerAddressEditFieldLabel  matlab.ui.control.Label
        PolarionServerAddressEditField  matlab.ui.control.EditField
        DescEgAddress                   matlab.ui.control.Label
        UserNameEditFieldLabel          matlab.ui.control.Label
        UserNameEditField               matlab.ui.control.EditField
        DescEgAddress_2                 matlab.ui.control.Label
        PingTestButton                  matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        PolarionAccessTokenLabel        matlab.ui.control.Label
        PolarionAccessTokenDesription   matlab.ui.control.Label
        AddChangeTokenButton            matlab.ui.control.Button
        WorkItemQueryTab                matlab.ui.container.Tab
        DescWorkItemsPanelLabel         matlab.ui.control.Label
        QueryStringEditFieldLabel       matlab.ui.control.Label
        QueryStringEditField            matlab.ui.control.EditField
        WorkItemTypeExampleLabel        matlab.ui.control.Label
        ProjectIDEditFieldLabel         matlab.ui.control.Label
        ProjectIDEditField              matlab.ui.control.EditField
        TestQueryButton                 matlab.ui.control.Button
        TestQueryStatusTextArea         matlab.ui.control.TextArea
        GenFromCSV                      matlab.ui.control.Button
        BaselineDropDownLabel           matlab.ui.control.Label
        BaselineDropDown                matlab.ui.control.DropDown
        ReadonlyCheckBox                matlab.ui.control.CheckBox
        RefreshBaselines                matlab.ui.control.Button
        HierarchyLinkRoleIDsEditFieldLabel  matlab.ui.control.Label
        HierarchyLinkRoleIDsEditField   matlab.ui.control.EditField
        WorkItemTypeExampleLabel_2      matlab.ui.control.Label
        LinkingMethodTab                matlab.ui.container.Tab
        LinkingMethodButtonGroup        matlab.ui.container.ButtonGroup
        DirectLinkingButton             matlab.ui.control.RadioButton
        SurrogateLinkingButton          matlab.ui.control.RadioButton
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SurrogateWorkItemTypeLabel      matlab.ui.control.Label
        SurrogateWTypeEditField         matlab.ui.control.EditField
        PushImageCheckBox               matlab.ui.control.CheckBox
        SurrogateLinkRoleLabel          matlab.ui.control.Label
        SurrogateLinkRoleEditField      matlab.ui.control.EditField
        SuspicionTab                    matlab.ui.container.Tab
        SpecifyaruletoselectlinkableelementsinSLSFMATLABfunctionLabel_2  matlab.ui.control.Label
        SuspicionModelDropDownLabel     matlab.ui.control.Label
        SuspicionModelDropDown          matlab.ui.control.DropDown
        RulesTab                        matlab.ui.container.Tab
        SpecifyaruletoselectlinkableelementsinSLSFMATLABfunctionLabel  matlab.ui.control.Label
        SLSFRuleFunction                matlab.ui.control.EditField
        SelectSLSFRuleButton            matlab.ui.control.Button
        RMITab                          matlab.ui.container.Tab
        TargetTypeDropDownLabel         matlab.ui.control.Label
        TargetTypeDropDown              matlab.ui.control.DropDown
        TargetsListBoxLabel             matlab.ui.control.Label
        TargetsListBox                  matlab.ui.control.ListBox
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        AddOpenTargetsButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        settingsBlockObj; % Description
        mgr simpol.Manager;
        simpolApp; % Description
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function refresh_gui(app)
            
            app.SimPolSettingsUIFigure.Name = app.mgr.allocationFilePath;
            
            cfg = simpol.utils.AllocConfig(app.mgr.allocationFilePath);
            
            if isprop(cfg, 'ServerURL')
                app.PolarionServerAddressEditField.Value = cfg.ServerURL;
            end
            
            if isprop(cfg, 'ProjectID')
                app.ProjectIDEditField.Value = cfg.ProjectID;
            end
            
            if isprop(cfg, 'QueryString')
                app.QueryStringEditField.Value = cfg.QueryString;
            end
            
            if isprop(cfg, 'LinkModel')
                idxButton = strcmp({app.LinkingMethodButtonGroup.Buttons.Text}', cfg.LinkModel);
                
                if sum(idxButton) == 1
                    app.LinkingMethodButtonGroup.SelectedObject = ...
                        app.LinkingMethodButtonGroup.Buttons(idxButton);
                else
                    % Otherwise the pre-selected
                end
                
            end
            
            if isprop(cfg, 'PushImage')
                app.PushImageCheckBox.Value = cfg.PushImage;
            end
            
            if isprop(cfg, 'SurrogateWorkItemType')
                app.SurrogateWTypeEditField.Value = cfg.SurrogateWorkItemType;
            end
            
            if isprop(cfg, 'SurrogateLinkRole')
                app.SurrogateLinkRoleEditField.Value = cfg.SurrogateLinkRole;
            end
            
            if isprop(cfg, 'SLSFSelectionRule')
                app.SLSFRuleFunction.Value = cfg.SLSFSelectionRule;
            end
            
            if isprop(cfg, 'BaselineRevision')
                if ~isempty(cfg.BaselineRevision)
                    app.BaselineDropDown.Items = {'none';cfg.BaselineRevision};
                    app.BaselineDropDown.Value = {cfg.BaselineRevision};
                    app.ReadonlyCheckBox.Value = true;
                end
            end
            
            if isprop(cfg, 'HierarchyLinkRoles')
                if ~isempty(cfg.HierarchyLinkRoles)
                    app.HierarchyLinkRoleIDsEditField.Value = strjoin(cfg.HierarchyLinkRoles, ',');
                end
            end
            
            if isprop(cfg, 'SuspicionModel')
                if ~isempty(cfg.SuspicionModel)
                    app.SuspicionModelDropDown.Value = cfg.SuspicionModel;
                end
            end
            
            if isprop(cfg, 'TargetType') && ~isempty(cfg.TargetType)
                try
                    app.TargetTypeDropDown.Value = feval([cfg.TargetType '.getName']);
                catch
                end
            end
            
            if isprop(cfg, 'Targets') && ~isempty(cfg.Targets)
                app.TargetsListBox.Items = cfg.Targets;
            end
            
            % Load values from preferences
            if ispref('SimPol')
                if ispref('SimPol', 'UserName')
                    app.UserNameEditField.Value = getpref('SimPol', 'UserName');
                else
                    app.UserNameEditField.Value = '';
                end
                
            end
        end
        
        function saveData(app)
            
            % Store form content in struct. Use a new struct, so that an error does not damage the
            % loaded config struct
            
            cfg = simpol.utils.AllocConfig(); % Create a default struct
            
            cfg.ServerURL = app.PolarionServerAddressEditField.Value;
            cfg.ProjectID = app.ProjectIDEditField.Value;
            cfg.QueryString = app.QueryStringEditField.Value;
            if ~strcmp(app.BaselineDropDown.Value, 'none')
                cfg.BaselineRevision = app.BaselineDropDown.Value;
            else
                cfg.BaselineRevision = '';
            end
            if isempty(app.HierarchyLinkRoleIDsEditField.Value)
                cfg.HierarchyLinkRoles = [];
            else
                cfg.HierarchyLinkRoles = strsplit(app.HierarchyLinkRoleIDsEditField.Value, ',');
            end
            cfg.PushImage = app.PushImageCheckBox.Value;
            cfg.LinkModel = app.LinkingMethodButtonGroup.SelectedObject.Text;
            cfg.SuspicionModel = app.SuspicionModelDropDown.Value;
            cfg.SurrogateWorkItemType = app.SurrogateWTypeEditField.Value;
            cfg.SurrogateLinkRole = app.SurrogateLinkRoleEditField.Value;
            cfg.SLSFSelectionRule = app.SLSFRuleFunction.Value;
            cfg.TargetType = app.getSelectedTargetClass();
            cfg.Targets = app.TargetsListBox.Items;
            
            cfg.save(app.mgr.allocationFilePath);
            
            % Store user credentials
            if ~ispref('SimPol')
                addpref('SimPol', 'UserName', app.UserNameEditField.Value);
            else
                % Store values in prefs
                setpref('SimPol', 'UserName', app.UserNameEditField.Value);
            end
        end
        
        function disableAll(app)
            app.PolarionServerAddressEditField.Enable = 'off';
            app.OKButton.Enable = 'off';
        end
        
        function enableAll(app)
            app.PolarionServerAddressEditField.Enable = 'on';
            app.OKButton.Enable = 'on';
        end
        
        function pb = createPolarionAdapter(app)
            pb = simpol.adapter.PolarionAdapter(app.mgr);
            pb.setServerURL(app.PolarionServerAddressEditField.Value);
            pb.setProjectID(app.ProjectIDEditField.Value);
            pb.setBaseQuery(app.QueryStringEditField.Value);
            pb.setCredentials(app.UserNameEditField.Value, '');
        end
        
        function failures = validateForm(app)
            
            failures = {};
            
            failBgColor = [1.00,0.75,0.80];
            
            if isempty(app.PolarionServerAddressEditField.Value)
                app.PolarionServerAddressEditField.BackgroundColor = failBgColor;
                failures{end+1} = 'Server address shall not be empty.';
            end
            
            if isempty(app.ProjectIDEditField.Value)
                app.ProjectIDEditField.BackgroundColor = failBgColor;
                failures{end+1} = 'Project ID shall not be empty.';
            end
            
            if strcmp(app.LinkingMethodButtonGroup.SelectedObject.Text, 'Surrogate Linking')
                if isempty(app.SurrogateWTypeEditField.Value)
                    app.SurrogateWTypeEditField.BackgroundColor = failBgColor;
                    failures{end+1} = 'Surrogate work item type shall not be empty.';
                end
                if isempty(app.SurrogateLinkRoleEditField.Value)
                    app.SurrogateLinkRoleEditField.BackgroundColor = failBgColor;
                    failures{end+1} = 'Surrogate link role shall not be empty.';
                end
            end
            
        end
        
        function clearValidation(app)
            whiteBg = [1 1 1];
            app.PolarionServerAddressEditField.BackgroundColor = whiteBg;
            app.ProjectIDEditField.BackgroundColor = whiteBg;
            app.SurrogateWTypeEditField.BackgroundColor = whiteBg;
            app.SurrogateLinkRoleEditField.BackgroundColor = whiteBg;
        end
        
        function className = getSelectedTargetClass(app)
            targetNames = cellfun(@(x) eval([x '.getName()']), app.mgr.registeredTargetTypes, 'UniformOutput', false);
            className = app.mgr.registeredTargetTypes(find(strcmp(targetNames, app.TargetTypeDropDown.Value), 1, 'first'));
            className = className{1};
        end
        
        function testQueryNotifyUser(app, eventData)
            app.TestQueryStatusTextArea.Value{end+1} = char(eventData.message);
        end
    end
    
    methods (Access = public)
        
        function close(app)
            app.simpolApp.releaseModalLock();
            app.delete();
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simpolApp)
            
            app.simpolApp = simpolApp;
            app.mgr = SimPol('instance');
            
            app.TargetTypeDropDown.Items = cellfun(@(x) eval([x '.getName()']),...
                app.mgr.registeredTargetTypes, 'UniformOutput', false);
            
            app.enableAll();
            app.refresh_gui();
            
        end

        % Button pushed function: OKButton
        function ok_clicked(app, event)
            try
                app.clearValidation();
                failures = app.validateForm();
                
                if ~isempty(failures)
                    uialert(app.SimPolSettingsUIFigure, ...
                        "Invalid input detected. Please review form:" + newline + ...
                        strjoin(failures, newline), "Validation Warning", "Icon", "Warn");
                    return;
                end
                
                app.saveData();
                
                if ~isempty(app.simpolApp) && isvalid(app.simpolApp)
                    app.simpolApp.reload('allocation', app.mgr.allocationFilePath);
                end
                
                app.close();
                
            catch ME
                app.mgr.notifyError(ME.message);
                return;
            end
        end

        % Button pushed function: CancelButton
        function cancel_clicked(app, event)
            app.close();
        end

        % Button pushed function: TestQueryButton
        function testConnection_clicked(app, event)
            
            app.TestQueryStatusTextArea.Value =  'Initiating query...';
            
            pause(0.5); % To see a change in the text field
            
            % Call test connection function
            try
                polarionAdapter = app.createPolarionAdapter();
                l = listener(polarionAdapter, "UserNotificationEvent",...
                    @(source, eventData) app.testQueryNotifyUser(eventData));
                polarionAdapter.updateServices();
                polarionAdapter.updateCache();
            catch e
                app.TestQueryStatusTextArea.Value = ['Error: ' e.message];
                
                if contains(e.message, 'Could not generate DH keypair')
                    uialert(app.SimPolSettingsUIFigure, ['Most often, this issue can be resolved by either (a) Updating Polarion to the latest release on the server. '...
                        '(b) Setting jdk.tls.disabledAlgorithms=DHE in JDK_HOME/jre/lib/security/java.security of your MATLAB client JRE.'],...
                        'Error Hint', 'Icon', 'Info');
                elseif contains(e.message, 'Bad envelope tag:  html')
                    uialert(app.SimPolSettingsUIFigure, ['Most often, this issue is cause when the server URL is directly set to the Polarion directory (e.g., myserver.com/polarion).'],...
                        'Error Hint', 'Icon', 'Info');
                end
            end
            
        end

        % Button pushed function: GenFromCSV
        function gen_from_export_clicked(app, event)
            s = inputdlg('Enter a comma-separted list of requriement IDs:');
            s = strtrim(s);
            
            if isempty(s)
                return;
            end
            
            try
                if ~isempty(strfind(s{1}, ';'))
                    ids = strsplit(s{1}, ';');
                else
                    ids = strsplit(s{1});
                end
                
                ids = strtrim(ids);
                
                s = strjoin(ids, ' OR ');
                
                s = strrep(s, '-', '\-');
                
                s = ['id:(' s, ')'];
                
                app.QueryStringEditField.Value = s;
                
            catch ME
                uialert(app.SimPolSettingsUIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: SelectSLSFRuleButton
        function slsf_selection_rule_clicked(app, event)
            [filename, pathname] = ...
                uigetfile({'*.m'},'Selection rule');
            
            if ~isempty(filename) && ~isempty(pathname) && exist(fullfile(pathname, filename),'file')
                [~, filename, ~] = fileparts(filename);
                app.SLSFRuleFunction.Value = filename;
            end
            
        end

        % Button pushed function: RefreshBaselines
        function refreshBaselines_clicked(app, event)
            polarionAdapter = app.createPolarionAdapter();
            polarionAdapter.updateServices();
            [names, revisions] = polarionAdapter.getBaselines();
            if isempty(names)
                app.BaselineDropDown.Items = [];
                app.BaselineDropDown.Value = [];
            else
                items = cellstr(string(names) + ' (' + revisions + ')');
                items = ['none'; items];
                app.BaselineDropDown.Items = items;
            end
        end

        % Value changed function: BaselineDropDown
        function baseline_changed(app, event)
            value = app.BaselineDropDown.Value;
            
            if strcmp(value, 'none')
                app.ReadonlyCheckBox.Value = false;
            else
                app.ReadonlyCheckBox.Value = true;
            end
            
        end

        % Button pushed function: AddButton
        function add_target_clicked(app, event)
            className = app.getSelectedTargetClass();
            [filename, pathname] = ...
                uigetfile(cellstr('*' + string(feval([className '.getTargetFileFilter']))),'Target');
            app.SimPolSettingsUIFigure.Visible = 'off';
            app.SimPolSettingsUIFigure.Visible = 'on';
            if ~isempty(filename) && ~isempty(pathname) && exist(fullfile(pathname, filename),'file')
                target = feval([className '.validateTarget'], fullfile(pathname, filename));
                if ~isempty(target) && ~any(strcmp(app.TargetsListBox.Items, target))
                    app.TargetsListBox.Items = sort([app.TargetsListBox.Items target]);
                end
            end
        end

        % Button pushed function: RemoveButton
        function remove_target_clicked(app, event)
            if ~isempty(app.TargetsListBox.Value)
                idx = find(strcmp(app.TargetsListBox.Items, app.TargetsListBox.Value), 1, 'first');
                currentItems = app.TargetsListBox.Items;
                if idx == 1
                    app.TargetsListBox.Items = currentItems(2:end);
                elseif idx == numel(currentItems)
                    app.TargetsListBox.Items = currentItems(1:end-1);
                else
                    app.TargetsListBox.Items = [currentItems(1:idx-1), currentItems(idx+1:end)];
                end
            end
        end

        % Button pushed function: AddOpenTargetsButton
        function add_open_targets_clicked(app, event)
            openTargets = feval([app.getSelectedTargetClass(), '.detectTargets']);
            if ~isempty(openTargets)
                app.TargetsListBox.Items = union(app.TargetsListBox.Items, openTargets);
            end
        end

        % Value changed function: TargetTypeDropDown
        function targetType_changed(app, event)
            app.TargetTypeDropDown.Value;
            app.TargetsListBox.Items = {};
        end

        % Button pushed function: PingTestButton
        function pingTest_clicked(app, event)
            
            try
                if isempty(app.PolarionServerAddressEditField.Value)
                    error('No valid URL entered.');
                end
                
                pb = simpol.adapter.PolarionAdapter(app.mgr);
                pb.setServerURL(app.PolarionServerAddressEditField.Value);
                
                pb.sessionService.hasSubject();
                
                uialert(app.SimPolSettingsUIFigure, 'Service found.',...
                    'Service found.', 'Icon', 'Success');
                delete(pb);
                
            catch ME
                assignin('base', 'SimPolErrorLog', ME);
                
                uialert(app.SimPolSettingsUIFigure, ['Cannot connect to Polarion services: Error:'...
                    char(ME.message) newline...
                    'Please refer to help for further information on the error.' newline ...
                    'Debug info has been written into the workspace.'],...
                    'Connecion error', 'Icon', 'Warn');
                
            end
        end

        % Button pushed function: HelpButton
        function help_clicked(app, event)
            open('SimPol User Guide.pdf');
        end

        % Close request function: SimPolSettingsUIFigure
        function shutdownFcn(app, event)
            
            app.simpolApp.releaseModalLock();
        
            delete(app)
            
        end

        % Value changed function: UserNameEditField
        function UserNameEditFieldValueChanged(app, event)
            value = app.UserNameEditField.Value;
            
        end

        % Button pushed function: AddChangeTokenButton
        function AddChangeTokenButtonPushed(app, event)
            Token = passwordEntryDialog('CheckPasswordLength', false, 'WindowName','Polarion Token');
            val = simpol.utils.Utils.encrypt(Token);
            if ispref('SimPol','PolarionToken')    
                setpref('SimPol','PolarionToken', val);
            else
                addpref('SimPol','PolarionToken', val);
            end
            ClockFigureStruct.a = clock;
            ClockFigureStruct.b = typecast(double(app.mgr.hSimPolGUI.UIFigure), 'uint8');
            if ispref('SimPol','Clock')    
                setpref('SimPol','Clock', ClockFigureStruct);
            else
                addpref('SimPol','Clock', ClockFigureStruct);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimPolSettingsUIFigure = uifigure('Visible', 'off');
            app.SimPolSettingsUIFigure.Name = 'SimPol Settings';
            app.SimPolSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @shutdownFcn, true);
            app.OKButton = uibutton(app.SimPolSettingsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @ok_clicked, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.SimPolSettingsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel_clicked, true);
            app.CancelButton.Text = 'Cancel';
            app.TabAdapter = uitabgroup(app.SimPolSettingsUIFigure);
            app.PolarionTab = uitab(app.TabAdapter);
            app.PolarionTab.Title = 'Polarion';
            app.TabGroup = uitabgroup(app.PolarionTab);
            app.ServerTab = uitab(app.TabGroup);
            app.ServerTab.Title = 'Server';
            app.PolarionServerAddressEditFieldLabel = uilabel(app.ServerTab);
            app.PolarionServerAddressEditFieldLabel.Text = 'Polarion Server Address';
            app.PolarionServerAddressEditField = uieditfield(app.ServerTab, 'text');
            app.DescEgAddress = uilabel(app.ServerTab);
            app.DescEgAddress.Text = {'Where your Polarion installation is located on the'; 'server ( e.g., https://polarion.mypage.de/polarion).'; 'Make sure this is not a forwarding URL. See help for SSL'; 'connections. '};
            app.UserNameEditFieldLabel = uilabel(app.ServerTab);
            app.UserNameEditFieldLabel.Text = 'Username';
            app.UserNameEditField = uieditfield(app.ServerTab, 'text');
            app.UserNameEditField.ValueChangedFcn = createCallbackFcn(app, @UserNameEditFieldValueChanged, true);
            app.DescEgAddress_2 = uilabel(app.ServerTab);
            app.DescEgAddress_2.Text = {'Optional'; 'The Username is stored in the preferences of MATLAB.'};
            app.PingTestButton = uibutton(app.ServerTab, 'push');
            app.PingTestButton.ButtonPushedFcn = createCallbackFcn(app, @pingTest_clicked, true);
            app.PingTestButton.Text = 'Ping Test';
            app.HelpButton = uibutton(app.ServerTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @help_clicked, true);
            app.HelpButton.Text = '?';
            app.PolarionAccessTokenLabel = uilabel(app.ServerTab);
            app.PolarionAccessTokenLabel.Text = 'Polarion Access Token';
            app.PolarionAccessTokenDesription = uilabel(app.ServerTab);
            app.PolarionAccessTokenDesription.Text = {'Optional'; 'Requires Polarion 22R1 or later.'; 'The token is stored in the preferences of MATLAB.'; 'If no token is specified, the user needs to provide '; 'the password for each connection.'};
            app.AddChangeTokenButton = uibutton(app.ServerTab, 'push');
            app.AddChangeTokenButton.ButtonPushedFcn = createCallbackFcn(app, @AddChangeTokenButtonPushed, true);
            app.AddChangeTokenButton.Text = 'Add/Change Token';
            app.WorkItemQueryTab = uitab(app.TabGroup);
            app.WorkItemQueryTab.Title = 'Work Item Query';
            app.DescWorkItemsPanelLabel = uilabel(app.WorkItemQueryTab);
            app.DescWorkItemsPanelLabel.Text = 'Specify a filter to query requirements/work items.';
            app.QueryStringEditFieldLabel = uilabel(app.WorkItemQueryTab);
            app.QueryStringEditFieldLabel.Text = 'Query String';
            app.QueryStringEditField = uieditfield(app.WorkItemQueryTab, 'text');
            app.WorkItemTypeExampleLabel = uilabel(app.WorkItemQueryTab);
            app.WorkItemTypeExampleLabel.Text = '(e.g., type:system_requirement)';
            app.ProjectIDEditFieldLabel = uilabel(app.WorkItemQueryTab);
            app.ProjectIDEditFieldLabel.Text = 'Project ID';
            app.ProjectIDEditField = uieditfield(app.WorkItemQueryTab, 'text');
            app.TestQueryButton = uibutton(app.WorkItemQueryTab, 'push');
            app.TestQueryButton.ButtonPushedFcn = createCallbackFcn(app, @testConnection_clicked, true);
            app.TestQueryButton.Text = 'Test Query';
            app.TestQueryStatusTextArea = uitextarea(app.WorkItemQueryTab);
            app.TestQueryStatusTextArea.Editable = 'off';
            app.GenFromCSV = uibutton(app.WorkItemQueryTab, 'push');
            app.GenFromCSV.ButtonPushedFcn = createCallbackFcn(app, @gen_from_export_clicked, true);
            app.GenFromCSV.Text = 'Generate from list';
            app.BaselineDropDownLabel = uilabel(app.WorkItemQueryTab);
            app.BaselineDropDownLabel.Text = 'Baseline';
            app.BaselineDropDown = uidropdown(app.WorkItemQueryTab);
            app.BaselineDropDown.Items = {'none'};
            app.BaselineDropDown.ValueChangedFcn = createCallbackFcn(app, @baseline_changed, true);
            app.BaselineDropDown.Value = 'none';
            app.ReadonlyCheckBox = uicheckbox(app.WorkItemQueryTab);
            app.ReadonlyCheckBox.Enable = 'off';
            app.ReadonlyCheckBox.Text = 'Read-only';
            app.RefreshBaselines = uibutton(app.WorkItemQueryTab, 'push');
            app.RefreshBaselines.ButtonPushedFcn = createCallbackFcn(app, @refreshBaselines_clicked, true);
            app.RefreshBaselines.Icon = 'simpol_reload.png';
            app.RefreshBaselines.Text = '';
            app.HierarchyLinkRoleIDsEditFieldLabel = uilabel(app.WorkItemQueryTab);
            app.HierarchyLinkRoleIDsEditFieldLabel.Text = {'Hierarchy'; 'Link Role IDs'};
            app.HierarchyLinkRoleIDsEditField = uieditfield(app.WorkItemQueryTab, 'text');
            app.WorkItemTypeExampleLabel_2 = uilabel(app.WorkItemQueryTab);
            app.WorkItemTypeExampleLabel_2.Text = {'Comma-separated IDs of link roles used to show a hierarchy.'; 'They can be found in the enumerations of the Polarion settings.'};
            app.LinkingMethodTab = uitab(app.TabGroup);
            app.LinkingMethodTab.Title = 'Linking Method';
            app.LinkingMethodButtonGroup = uibuttongroup(app.LinkingMethodTab);
            app.LinkingMethodButtonGroup.Title = 'Linking Method';
            app.DirectLinkingButton = uiradiobutton(app.LinkingMethodButtonGroup);
            app.DirectLinkingButton.Text = 'Direct Linking';
            app.DirectLinkingButton.Value = true;
            app.SurrogateLinkingButton = uiradiobutton(app.LinkingMethodButtonGroup);
            app.SurrogateLinkingButton.Text = 'Surrogate Linking';
            app.Label = uilabel(app.LinkingMethodButtonGroup);
            app.Label.Text = {'Requirements in Polarion are directly linked to Simulink by adding an'; 'hyperlink. This modifies the requirement work item.'};
            app.Label_2 = uilabel(app.LinkingMethodButtonGroup);
            app.Label_2.Text = {'For every linked Simulink item, a surrogate work item is automatically'; 'created and managed. Both the Simulink items and the Polarion requirements'; 'link to the surrogate working item.'};
            app.SurrogateWorkItemTypeLabel = uilabel(app.LinkingMethodButtonGroup);
            app.SurrogateWorkItemTypeLabel.Text = 'Surrogate Work Item Type';
            app.SurrogateWTypeEditField = uieditfield(app.LinkingMethodButtonGroup, 'text');
            app.PushImageCheckBox = uicheckbox(app.LinkingMethodButtonGroup);
            app.PushImageCheckBox.Text = 'Add picture of Simulink block for every link in Polarion';
            app.SurrogateLinkRoleLabel = uilabel(app.LinkingMethodButtonGroup);
            app.SurrogateLinkRoleLabel.Text = 'Surrogate Link Role';
            app.SurrogateLinkRoleEditField = uieditfield(app.LinkingMethodButtonGroup, 'text');
            app.SuspicionTab = uitab(app.TabGroup);
            app.SuspicionTab.Title = 'Suspicion';
            app.SpecifyaruletoselectlinkableelementsinSLSFMATLABfunctionLabel_2 = uilabel(app.SuspicionTab);
            app.SpecifyaruletoselectlinkableelementsinSLSFMATLABfunctionLabel_2.Text = {'Specify, how RMI items are suspected:'; ''; 'Revision Mode:'; 'When establishing a link, the revision of the requirement is stored with the link.'; 'Any update of the Polarion work item suspects the linked model item.'; ''; 'Auto-Suspicion Mode:'; 'Propagates the auto-suspicion of the Polarion work item.'; ''};
            app.SuspicionModelDropDownLabel = uilabel(app.SuspicionTab);
            app.SuspicionModelDropDownLabel.Text = 'Suspicion Model';
            app.SuspicionModelDropDown = uidropdown(app.SuspicionTab);
            app.SuspicionModelDropDown.Items = {'AutoSuspect', 'Revision'};
            app.SuspicionModelDropDown.Value = 'Revision';
            app.RulesTab = uitab(app.TabGroup);
            app.RulesTab.Title = 'Rules';
            app.SpecifyaruletoselectlinkableelementsinSLSFMATLABfunctionLabel = uilabel(app.RulesTab);
            app.SpecifyaruletoselectlinkableelementsinSLSFMATLABfunctionLabel.Text = 'Specify a rule to select linkable elements in SL/SF (MATLAB function):';
            app.SLSFRuleFunction = uieditfield(app.RulesTab, 'text');
            app.SelectSLSFRuleButton = uibutton(app.RulesTab, 'push');
            app.SelectSLSFRuleButton.ButtonPushedFcn = createCallbackFcn(app, @slsf_selection_rule_clicked, true);
            app.SelectSLSFRuleButton.Icon = 'simpol_open.png';
            app.SelectSLSFRuleButton.Text = 'Select Rule';
            app.RMITab = uitab(app.TabAdapter);
            app.RMITab.Title = 'RMI';
            app.TargetTypeDropDownLabel = uilabel(app.RMITab);
            app.TargetTypeDropDownLabel.Text = 'Target Type';
            app.TargetTypeDropDown = uidropdown(app.RMITab);
            app.TargetTypeDropDown.Items = {};
            app.TargetTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @targetType_changed, true);
            app.TargetTypeDropDown.Value = {};
            app.TargetsListBoxLabel = uilabel(app.RMITab);
            app.TargetsListBoxLabel.Text = 'Targets';
            app.TargetsListBox = uilistbox(app.RMITab);
            app.TargetsListBox.Items = {};
            app.TargetsListBox.Value = {};
            app.RemoveButton = uibutton(app.RMITab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @remove_target_clicked, true);
            app.RemoveButton.Text = 'Remove';
            app.AddButton = uibutton(app.RMITab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @add_target_clicked, true);
            app.AddButton.Text = 'Add';
            app.AddOpenTargetsButton = uibutton(app.RMITab, 'push');
            app.AddOpenTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @add_open_targets_clicked, true);
            app.AddOpenTargetsButton.Text = 'Add Open Targets';
            % Show the figure after all components are created
            app.SimPolSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimPol_Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimPolSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimPolSettingsUIFigure)
        end
    end
end